classdef transferGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GenerateTransferButton         matlab.ui.control.Button
        TimeofFlightTUEditField        matlab.ui.control.NumericEditField
        TimeofFlightTUEditFieldLabel   matlab.ui.control.Label
        GenerateTransferLabel          matlab.ui.control.Label
        t_0ontargetorbitSlider         matlab.ui.control.Slider
        t_0ontargetorbitSliderLabel    matlab.ui.control.Label
        t_0onstartingorbitSlider       matlab.ui.control.Slider
        t_0onstartingorbitSliderLabel  matlab.ui.control.Label
        JacobiConstantSlider_2         matlab.ui.control.Slider
        JacobiConstantSlider_2Label    matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        TargetOrbitDropDown            matlab.ui.control.DropDown
        TargetOrbitDropDownLabel       matlab.ui.control.Label
        StartingOrbitDropDown          matlab.ui.control.DropDown
        StartingOrbitDropDownLabel     matlab.ui.control.Label
        JacobiConstantSlider           matlab.ui.control.Slider
        JacobiConstantSliderLabel      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % adding comment to check for github
    
    properties (Access = private)
        s_data, t_data
        s_orbit, t_orbit
        CCs, CCt
        opts113
        mu
        Xstarting, Xtarget
        tstarting, ttarget
        X0, Xf
        s_point, t_point
        TOF
        Tp, Xp, U
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % addpath("planet3D\")
            % Moon_opts.Position = [384e6,0,0];
            % hold(app.UIAxes,"on")
            % planet3D(app.UIAxes,'Earth')
            % planet3D(app.UIAxes,'Moon',Moon_opts)
            % app.UIAxes.CameraPosition = [-0.8450   -1.3485    0.9813];
            % axis(app.UIAxes,"equal")
            % grid (app.UIAxes,"on")
            % box(app.UIAxes,"on")
            % view(app.UIAxes,45,45)
            
            addpath("orbit database\")
            
            app.UIFigure.Theme = 'dark';
            
            app.mu = 1.215058560962404E-2;

            list_of_families = ls("orbit database\*.csv");
            array_of_families = cell(1,size(list_of_families,1));
            for i = 1:size(list_of_families,1)
                array_of_families{i} = list_of_families(i,:);
            end


            app.StartingOrbitDropDown.Items = array_of_families;
            app.TargetOrbitDropDown.Items = array_of_families;
            % plotting Earth-Moon and Lagrange Points
            
            Re = 6378/389703;
            Rm = 1737/389703;

            [Xe,Ye,Ze] = ellipsoid(app.UIAxes,0,0,0,Re,Re,Re,400);
            [Xm,Ym,Zm] = ellipsoid(app.UIAxes,1,0,0,Rm,Rm,Rm,400);
            
            xL1 = Lptpos(app.mu,1);
            xL2 = Lptpos(app.mu,2);
            xL3 = Lptpos(app.mu,3);

            hold (app.UIAxes, 'on')
            surf(app.UIAxes,Xe,Ye,Ze,'EdgeColor','b','FaceColor','flat')
            surf(app.UIAxes,Xm,Ym,Zm,'EdgeColor',[0.9,0.9,0.9],'FaceColor','flat')
            plot3(app.UIAxes, xL1, 0, 0, 'rx')
            plot3(app.UIAxes, xL2, 0, 0, 'rx')
            plot3(app.UIAxes, xL3, 0, 0, 'rx')
            plot3(app.UIAxes,1/2,sqrt(3)/2,0,'rx','LineWidth',1)
            plot3(app.UIAxes,1/2,-sqrt(3)/2,0,'rx','LineWidth',1)
            hold (app.UIAxes,'off')

            %view(app.UIAxes,[-5 2 5])
            xlabel(app.UIAxes,'$x$ [DU]','FontSize',14,'Interpreter','latex')
            ylabel(app.UIAxes,'$y$ [DU]','FontSize',14,'Interpreter','latex')
            zlabel(app.UIAxes,'$z$ [DU]','FontSize',14,'Interpreter','latex')
            title(app.UIAxes,'')
            axis(app.UIAxes,'equal')
            view(app.UIAxes,[0,0,1])
            grid(app.UIAxes,"on")
            box(app.UIAxes,"on")


            app.StartingOrbitDropDown.Value

            app.opts113 = odeset('RelTol',1e-10,'AbsTol',1e-11);

            app.StartingOrbitDropDownValueChanged(app);
            app.TargetOrbitDropDownValueChanged(app);
        end

        % Value changed function: StartingOrbitDropDown
        function StartingOrbitDropDownValueChanged(app, event)
            value = app.StartingOrbitDropDown.Value;

                clear app.s_data
                delete (app.s_orbit)
                s_data_name = value;
                app.s_data = readmatrix(s_data_name);
                app.CCs = app.s_data(:,8);
                Cmin = min(app.CCs);
                Cmax = max(app.CCs);
                app.JacobiConstantSlider.Limits=[Cmin,Cmax];
                app.EditField.Value = Cmin;

        end

        % Value changed function: JacobiConstantSlider
        function JacobiConstantSliderValueChanged(app, event)
            value = app.JacobiConstantSlider.Value;

            [~,ind] = min(abs(value - app.CCs));
            C = app.CCs(ind);
            app.EditField.Value = C;

            X0 = app.s_data(ind,2:7)';
            T = app.s_data(ind,9);

            %tic;
            [app.tstarting,app.Xstarting] = ode113(@CR3BPMC,[0,T],X0,app.opts113);
            %toc;
            x = app.Xstarting(:,1); y = app.Xstarting(:,2); z = app.Xstarting(:,3);
            delete(app.s_orbit)
            hold(app.UIAxes,'on')
            app.s_orbit = plot3(app.UIAxes,x,y,z,'c','LineWidth',2);
            hold(app.UIAxes,'off')
        end

        % Value changing function: JacobiConstantSlider
        function JacobiConstantSliderValueChanging(app, event)
            changingValue = event.Value;
            value = changingValue;

            [~,ind] = min(abs(value - app.CCs));
            C = app.CCs(ind);
            app.EditField.Value = C;

            X0 = app.s_data(ind,2:7)';
            T = app.s_data(ind,9);

            %tic;
            [app.tstarting,app.Xstarting] = ode113(@CR3BPMC,[0,T],X0,app.opts113);
            %toc;
            x = app.Xstarting(:,1); y = app.Xstarting(:,2); z = app.Xstarting(:,3);
            delete(app.s_orbit)
            hold(app.UIAxes,'on')
            app.s_orbit = plot3(app.UIAxes,x,y,z,'c','LineWidth',2);
            hold(app.UIAxes,'off')
        end

        % Value changed function: TargetOrbitDropDown
        function TargetOrbitDropDownValueChanged(app, event)
            value = app.TargetOrbitDropDown.Value;
                        

                clear app.t_data
                delete (app.t_orbit)
                t_data_name = value;
                app.t_data = readmatrix(t_data_name);
                app.CCt = app.t_data(:,8);
                Cmin = min(app.CCt);
                Cmax = max(app.CCt);
                app.JacobiConstantSlider_2.Limits=[Cmin,Cmax];
                app.EditField_2.Value = Cmin;

        end

        % Value changed function: JacobiConstantSlider_2
        function JacobiConstantSlider_2ValueChanged(app, event)
            value = app.JacobiConstantSlider_2.Value;
            
            [~,ind] = min(abs(value - app.CCt));
            C = app.CCt(ind);
            app.EditField_2.Value = C;

            X0 = app.t_data(ind,2:7)';
            T = app.t_data(ind,9);

            %tic;
            [app.ttarget,app.Xtarget] = ode113(@CR3BPMC,[0,T],X0,app.opts113);
            %toc;
            x = app.Xtarget(:,1); y = app.Xtarget(:,2); z = app.Xtarget(:,3);
            delete(app.t_orbit)
            hold(app.UIAxes,'on')
            app.t_orbit = plot3(app.UIAxes,x,y,z,'Color',[1,0,1],'LineWidth',2);
            hold(app.UIAxes,'off')
        end

        % Value changing function: JacobiConstantSlider_2
        function JacobiConstantSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            value = changingValue;

            [~,ind] = min(abs(value - app.CCt));
            C = app.CCt(ind);
            app.EditField_2.Value = C;

            X0 = app.t_data(ind,2:7)';
            T = app.t_data(ind,9);

            %tic;
            [~,X] = ode113(@CR3BPMC,[0,T],X0,app.opts113);
            %toc;
            x = X(:,1); y = X(:,2); z = X(:,3);
            delete(app.t_orbit)
            hold(app.UIAxes,'on')
            app.t_orbit = plot3(app.UIAxes,x,y,z,'Color',[1,0,1],'LineWidth',2);
            hold(app.UIAxes,'off')
        end

        % Value changing function: t_0onstartingorbitSlider
        function t_0onstartingorbitSliderValueChanging(app, event)
            changingValue = event.Value;

            if ~isempty(app.tstarting)
                tau = app.tstarting/app.tstarting(end);
                [~,ind] = min(abs(changingValue-tau));
                app.X0 = app.Xstarting(ind,:);

                delete(app.s_point);
                hold(app.UIAxes,"on")
                app.s_point = plot3(app.UIAxes, app.X0(1),app.X0(2),app.X0(3),'ro','LineWidth',3,'MarkerFaceColor','r');
                hold(app.UIAxes,"off")

            end
        end

        % Value changing function: t_0ontargetorbitSlider
        function t_0ontargetorbitSliderValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(app.ttarget)
                tau = app.ttarget/app.ttarget(end);
                [~,ind] = min(abs(changingValue-tau));
                app.Xf = app.Xtarget(ind,:);

                delete(app.t_point);
                hold(app.UIAxes,"on")
                app.t_point = plot3(app.UIAxes, app.Xf(1),app.Xf(2),app.Xf(3),'ro','LineWidth',3,'MarkerFaceColor','r');
                hold(app.UIAxes,"off")

            end
        end

        % Value changed function: TimeofFlightTUEditField
        function TimeofFlightTUEditFieldValueChanged(app, event)
            value = app.TimeofFlightTUEditField.Value;
            app.TOF = value;
        end

        % Button pushed function: GenerateTransferButton
        function GenerateTransferButtonPushed(app, event)
            if ~isempty(app.X0) && ~isempty(app.Xf) && ~isempty(app.TOF)
                %% Solving TPBVP using BVP4C
                [app.Tp,app.Xp,app.U] = generateTransfer(app.X0', app.Xf', app.TOF,app.mu,app.opts113);

                %% plot the transfer

                x = app.Xp(:,1); y = app.Xp(:,2); z = app.Xp(:,3);

                hold(app.UIAxes,"on")
                plot3(app.UIAxes,x,y,z,'w','LineWidth',2)
                hold(app.UIAxes,"off")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, {''; 'Y'})
            zlabel(app.UIAxes, 'Z')
            app.JacobiConstantSliderLabel = uilabel(app.UIFigure);
            app.JacobiConstantSliderLabel.Interpreter = 'latex';
            app.JacobiConstantSliderLabel.Text = 'Jacobi Constant';
            app.JacobiConstantSlider = uislider(app.UIFigure);
            app.JacobiConstantSlider.ValueChangedFcn = createCallbackFcn(app, @JacobiConstantSliderValueChanged, true);
            app.JacobiConstantSlider.ValueChangingFcn = createCallbackFcn(app, @JacobiConstantSliderValueChanging, true);
            app.StartingOrbitDropDownLabel = uilabel(app.UIFigure);
            app.StartingOrbitDropDownLabel.Interpreter = 'latex';
            app.StartingOrbitDropDownLabel.Text = 'Starting Orbit';
            app.StartingOrbitDropDown = uidropdown(app.UIFigure);
            app.StartingOrbitDropDown.Items = {'<Select>'};
            app.StartingOrbitDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingOrbitDropDownValueChanged, true);
            app.StartingOrbitDropDown.Placeholder = '<Select>';
            app.StartingOrbitDropDown.Value = '<Select>';
            app.TargetOrbitDropDownLabel = uilabel(app.UIFigure);
            app.TargetOrbitDropDownLabel.Interpreter = 'latex';
            app.TargetOrbitDropDownLabel.Text = 'Target Orbit';
            app.TargetOrbitDropDown = uidropdown(app.UIFigure);
            app.TargetOrbitDropDown.Items = {'<Select>'};
            app.TargetOrbitDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetOrbitDropDownValueChanged, true);
            app.TargetOrbitDropDown.Placeholder = '<Select>';
            app.TargetOrbitDropDown.Value = '<Select>';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%11.14g';
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%11.14g';
            app.EditField_2.Editable = 'off';
            app.JacobiConstantSlider_2Label = uilabel(app.UIFigure);
            app.JacobiConstantSlider_2Label.Interpreter = 'latex';
            app.JacobiConstantSlider_2Label.Text = 'Jacobi Constant';
            app.JacobiConstantSlider_2 = uislider(app.UIFigure);
            app.JacobiConstantSlider_2.ValueChangedFcn = createCallbackFcn(app, @JacobiConstantSlider_2ValueChanged, true);
            app.JacobiConstantSlider_2.ValueChangingFcn = createCallbackFcn(app, @JacobiConstantSlider_2ValueChanging, true);
            app.t_0onstartingorbitSliderLabel = uilabel(app.UIFigure);
            app.t_0onstartingorbitSliderLabel.Interpreter = 'latex';
            app.t_0onstartingorbitSliderLabel.Text = '$t_0$ on starting orbit';
            app.t_0onstartingorbitSlider = uislider(app.UIFigure);
            app.t_0onstartingorbitSlider.Limits = [0 1];
            app.t_0onstartingorbitSlider.MajorTicks = [0 0.5 1];
            app.t_0onstartingorbitSlider.MajorTickLabels = {'0', '0.5T', 'T'};
            app.t_0onstartingorbitSlider.ValueChangingFcn = createCallbackFcn(app, @t_0onstartingorbitSliderValueChanging, true);
            app.t_0ontargetorbitSliderLabel = uilabel(app.UIFigure);
            app.t_0ontargetorbitSliderLabel.Interpreter = 'latex';
            app.t_0ontargetorbitSliderLabel.Text = '$t_0$ on target orbit';
            app.t_0ontargetorbitSlider = uislider(app.UIFigure);
            app.t_0ontargetorbitSlider.Limits = [0 1];
            app.t_0ontargetorbitSlider.MajorTicks = [0 0.5 1];
            app.t_0ontargetorbitSlider.MajorTickLabels = {'0', '0.5T', 'T'};
            app.t_0ontargetorbitSlider.ValueChangingFcn = createCallbackFcn(app, @t_0ontargetorbitSliderValueChanging, true);
            app.GenerateTransferLabel = uilabel(app.UIFigure);
            app.GenerateTransferLabel.Interpreter = 'latex';
            app.GenerateTransferLabel.Text = 'Generate Transfer';
            app.TimeofFlightTUEditFieldLabel = uilabel(app.UIFigure);
            app.TimeofFlightTUEditFieldLabel.Interpreter = 'latex';
            app.TimeofFlightTUEditFieldLabel.Text = 'Time of Flight [TU]';
            app.TimeofFlightTUEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeofFlightTUEditField.ValueChangedFcn = createCallbackFcn(app, @TimeofFlightTUEditFieldValueChanged, true);
            app.GenerateTransferButton = uibutton(app.UIFigure, 'push');
            app.GenerateTransferButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTransferButtonPushed, true);
            app.GenerateTransferButton.Text = 'Generate Transfer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transferGen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end